classdef gameplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button_6                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        PickyouravatarDropDown_2       matlab.ui.control.DropDown
        PickyouravatarDropDown_2Label  matlab.ui.control.Label
        PickyouravatarDropDown         matlab.ui.control.DropDown
        PickyouravatarDropDownLabel    matlab.ui.control.Label
        ScoreBreakdownLabel            matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        GameInfoLabel                  matlab.ui.control.Label
        Player1sturnLabel              matlab.ui.control.Label
        Player2nameEditField           matlab.ui.control.EditField
        Player2nameEditFieldLabel      matlab.ui.control.Label
        Player1nameEditField           matlab.ui.control.EditField
        Player1nameEditFieldLabel      matlab.ui.control.Label
        RolldiceButton                 matlab.ui.control.Button
        RestartButton                  matlab.ui.control.Button
        BankPointsButton               matlab.ui.control.Button
        RulesButton                    matlab.ui.control.Button
        SetAsideButton                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        OnlineButton                   matlab.ui.control.Button
        LocalButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        game      Game % Description
        dice 
        currentMeld
    end
    
    methods (Access = public)
        function app = updateTable(app)
            % Update Table UI
            app.UITable.Data = [app.game.p1.name app.game.p1.score; app.game.p2.name app.game.p2.score];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.game = Game("P1", "P2");
            app.dice = DiceArray();
            app.updateTable();
        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            rules
        end

        % Button pushed function: RolldiceButton
        function RolldiceButtonPushed(app, event)

            % Update GUI images
            for i = 1:7
                rolls = randi(6, 1, 6);
                app.one.ImageSource = string(fullfile('images','dice', char("dice" + rolls(1) + ".png")));
                app.two.ImageSource = string(fullfile('images','dice', char("dice" + rolls(2) + ".png")));
                app.three.ImageSource = string(fullfile('images','dice', char("dice" + rolls(3) + ".png")));
                app.four.ImageSource = string(fullfile('images','dice', char("dice" + rolls(4) + ".png")));
                app.five.ImageSource = string(fullfile('images','dice', char("dice" + rolls(5) + ".png")));
                app.six.ImageSource = string(fullfile('images','dice', char("dice" + rolls(6) + ".png")));
                pause(0.1);
            end

            % Update the DiceArray
            app.dice = app.dice.updateValues(rolls);
            app.game.p2.score = sum(rolls);
            app.updateTable();

            % Generate melds.
            [score, meld] = app.dice.generateMelds();
            app.game.p1.score = score;
            app.updateTable();
        end

        % Callback function: not associated with a component
        function oneImageClicked(app, event)
            app.dice = app.dice.changeSelection(1);
            if (app.dice.selected(1)); app.one.BackgroundColor = 'r'; else; app.one.BackgroundColor = 'none'; end
        end

        % Callback function: not associated with a component
        function twoImageClicked(app, event)
            app.dice = app.dice.changeSelection(2);
            if (app.dice.selected(2)); app.two.BackgroundColor = 'r'; else; app.two.BackgroundColor = 'none'; end
        end

        % Callback function: not associated with a component
        function threeImageClicked(app, event)
            app.dice = app.dice.changeSelection(3);
            if (app.dice.selected(3)); app.three.BackgroundColor = 'r'; else; app.three.BackgroundColor = 'none'; end
        end

        % Callback function: not associated with a component
        function fourImageClicked(app, event)
            app.dice = app.dice.changeSelection(4);
            if (app.dice.selected(4)); app.four.BackgroundColor = 'r'; else; app.four.BackgroundColor = 'none'; end
        end

        % Callback function: not associated with a component
        function fiveImageClicked(app, event)
            app.dice = app.dice.changeSelection(5);
            if (app.dice.selected(5)); app.five.BackgroundColor = 'r'; else; app.five.BackgroundColor = 'none'; end
        end

        % Callback function: not associated with a component
        function sixImageClicked(app, event)
            app.dice = app.dice.changeSelection(6);
            if (app.dice.selected(6)); app.six.BackgroundColor = 'r'; else; app.six.BackgroundColor = 'none'; end
        end

        % Button pushed function: BankPointsButton
        function BankPointsButtonPushed(app, event)
            
        end

        % Callback function: not associated with a component
        function TestButtonPushed(app, event)
            app.TestButton.BackgroundColor = 'r';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button.BackgroundColor = 'y';
            if (app.dice.selected(1))
                app.Button.BackgroundColor = 'y'; 
            else
                app.Button.BackgroundColor = 'none';
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button_2.BackgroundColor = 'y';
            if (app.dice.selected(2))
                app.Button_2.BackgroundColor = 'y'; 
            else
                app.Button_2.BackgroundColor = 'none';
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Button_3.BackgroundColor = 'y';
            if (app.dice.selected(3))
                app.Button_3.BackgroundColor = 'y'; 
            else
                app.Button_3.BackgroundColor = 'none';
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Button_4.BackgroundColor = 'y';
            if (app.dice.selected(4))
                app.Button_4.BackgroundColor = 'y'; 
            else
                app.Button_4.BackgroundColor = 'none';
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Button_5.BackgroundColor = 'y';
            if (app.dice.selected(5))
                app.Button_5.BackgroundColor = 'y'; 
            else
                app.Button_5.BackgroundColor = 'none';
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Button_6.BackgroundColor = 'y';
            if (app.dice.selected(6))
                app.Button_6.BackgroundColor = 'y'; 
            else
                app.Button_6.BackgroundColor = 'none';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'prism');
            app.UIFigure.Name = 'MATLAB App';
            app.LocalButton = uibutton(app.UIFigure, 'push');
            app.LocalButton.Text = 'Local';
            app.OnlineButton = uibutton(app.UIFigure, 'push');
            app.OnlineButton.Text = 'Online';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player Name'; 'Score'};
            app.UITable.RowName = {};
            app.SetAsideButton = uibutton(app.UIFigure, 'push');
            app.SetAsideButton.Text = 'Set Aside';
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.BankPointsButton = uibutton(app.UIFigure, 'push');
            app.BankPointsButton.ButtonPushedFcn = createCallbackFcn(app, @BankPointsButtonPushed, true);
            app.BankPointsButton.Text = 'Bank Points';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.Text = 'Restart';
            app.RolldiceButton = uibutton(app.UIFigure, 'push');
            app.RolldiceButton.ButtonPushedFcn = createCallbackFcn(app, @RolldiceButtonPushed, true);
            app.RolldiceButton.Text = 'Roll dice';
            app.Player1nameEditFieldLabel = uilabel(app.UIFigure);
            app.Player1nameEditFieldLabel.Text = 'Player 1 name:';
            app.Player1nameEditField = uieditfield(app.UIFigure, 'text');
            app.Player2nameEditFieldLabel = uilabel(app.UIFigure);
            app.Player2nameEditFieldLabel.Text = 'Player 2 name:';
            app.Player2nameEditField = uieditfield(app.UIFigure, 'text');
            app.Player1sturnLabel = uilabel(app.UIFigure);
            app.Player1sturnLabel.Text = 'Player 1''s turn';
            app.GameInfoLabel = uilabel(app.UIFigure);
            app.GameInfoLabel.Text = 'Game Info:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'scoring.png');
            app.ScoreBreakdownLabel = uilabel(app.UIFigure);
            app.ScoreBreakdownLabel.Text = 'Score Breakdown';
            app.PickyouravatarDropDownLabel = uilabel(app.UIFigure);
            app.PickyouravatarDropDownLabel.Text = 'Pick your avatar';
            app.PickyouravatarDropDown = uidropdown(app.UIFigure);
            app.PickyouravatarDropDown_2Label = uilabel(app.UIFigure);
            app.PickyouravatarDropDown_2Label.Text = 'Pick your avatar';
            app.PickyouravatarDropDown_2 = uidropdown(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'images', 'dice', 'dice1.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'images', 'dice', 'dice2.png');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'images', 'dice', 'dice3.png');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'images', 'dice', 'dice4.png');
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'images', 'dice', 'dice5.png');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'images', 'dice', 'dice6.png');
            app.Button_6.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end