classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ChatBoxLabel               matlab.ui.control.Label
        UITable3                   matlab.ui.control.Table
        SendButton                 matlab.ui.control.Button
        EnteramessageforyouropponentEditField  matlab.ui.control.EditField
        EnteramessageforyouropponentEditFieldLabel  matlab.ui.control.Label
        FarkleDiceGameLabel        matlab.ui.control.Label
        DebugEditField             matlab.ui.control.EditField
        DebugEditFieldLabel        matlab.ui.control.Label
        GameStatusLabel            matlab.ui.control.Label
        UITable2                   matlab.ui.control.Table
        SetNameButton              matlab.ui.control.Button
        Lamp2                      matlab.ui.control.Lamp
        Lamp                       matlab.ui.control.Lamp
        Label                      matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        five                       matlab.ui.control.Image
        six                        matlab.ui.control.Image
        four                       matlab.ui.control.Image
        three                      matlab.ui.control.Image
        two                        matlab.ui.control.Image
        one                        matlab.ui.control.Image
        Player2nameEditField       matlab.ui.control.EditField
        Player2nameEditFieldLabel  matlab.ui.control.Label
        Player1nameEditField       matlab.ui.control.EditField
        Player1nameEditFieldLabel  matlab.ui.control.Label
        RollDiceButton             matlab.ui.control.Button
        RestartButton              matlab.ui.control.Button
        BankPointsButton           matlab.ui.control.Button
        RulesButton                matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        OnlineButton               matlab.ui.control.Button
        LocalButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        game      Game
        dice 
        rolls
        validDice
        triples
        numSelected

        turn
        turnNumber = 0
        p1name
        p2name
        p1score = 0;
        p2score = 0;
        previousRolls = zeros(2, 6);

        channelID = 2061872;
        writeAPI = '2ELG9UY53CY4TOVS';  
        readAPI = 'AU8BCNTQ49XS1W9G';
        online = false;
        player
    end
    
    methods (Access = public)
        function app = updateTable(app)
            if (app.online)
                pause(1);
                app.p1score = thingSpeakRead(app.channelID, "Fields", 3, "ReadKey", app.readAPI);
                disp(app.p1score);
                pause(1);
                app.p2score = thingSpeakRead(app.channelID, "Fields", 4, "ReadKey", app.readAPI);
                disp(app.p2score);
                pause(1);
                %app.p1name = thingSpeakRead(app.channelID, "Fields", 1, "ReadKey", app.readAPI);
                tbl = thingSpeakRead(app.channelID, "Fields", 1, "ReadKey", app.readAPI, 'OutputFormat', 'table');
                disp(tbl);
                app.p1name = string(tbl{1, 2});
                disp(app.p1name);
                pause(1);
                tbl = thingSpeakRead(app.channelID, "Fields", 2, "ReadKey", app.readAPI, 'OutputFormat','table');
                app.p2name = string(tbl{1, 2});
                disp(app.p2name);
            end
            app.UITable.Data = [app.p1name app.p1score; app.p2name app.p2score];
        end
        function app = updateTable2(app)
            disp(app.turnNumber);
            disp(strjoin(string(app.previousRolls(1, :))))
            disp(strjoin(string(app.previousRolls(2, :))))
            newData = [app.turnNumber, strjoin(string(app.previousRolls(1, :))), strjoin(string(app.previousRolls(2, :)))];

            app.UITable2.Data = [app.UITable2.Data; newData];
        end

        function app = updateDiceImages(app, arr)
            app.one.ImageSource = string(fullfile('images','dice', char("dice" + arr(1) + ".png")));
            app.two.ImageSource = string(fullfile('images','dice', char("dice" + arr(2) + ".png")));
            app.three.ImageSource = string(fullfile('images','dice', char("dice" + arr(3) + ".png")));
            app.four.ImageSource = string(fullfile('images','dice', char("dice" + arr(4) + ".png")));
            app.five.ImageSource = string(fullfile('images','dice', char("dice" + arr(5) + ".png")));
            app.six.ImageSource = string(fullfile('images','dice', char("dice" + arr(6) + ".png")));

            % TO DO: Update selection background highlight.
            if (app.dice.isSelected(1)); app.one.BackgroundColor = 'r'; else; app.one.BackgroundColor = 'none'; end
            if (app.dice.isSelected(2)); app.two.BackgroundColor = 'r'; else; app.two.BackgroundColor = 'none'; end
            if (app.dice.isSelected(3)); app.three.BackgroundColor = 'r'; else; app.three.BackgroundColor = 'none'; end
            if (app.dice.isSelected(4)); app.four.BackgroundColor = 'r'; else; app.four.BackgroundColor = 'none'; end
            if (app.dice.isSelected(5)); app.five.BackgroundColor = 'r'; else; app.five.BackgroundColor = 'none'; end
            if (app.dice.isSelected(6)); app.six.BackgroundColor = 'r'; else; app.six.BackgroundColor = 'none'; end
        end
        
        function app = farkle(app)
            app.Label.Text = "Farkle!";
            app.dice = app.dice.unselectAll();
            app.updateDiceImages(app.dice.allValues());
            pause(1);
            app.changeTurn();
            app.Label.Text = "Play";
            if (~app.online)
                app.RollDiceButton.Enable = 'on';
            end
        end
        
        function app = changeTurn(app)
            app.BankPointsButton.Enable = 'off';
            app.turn = app.turn*-1;
            %app.GameStatusLabel.Text = string(app.turn);
            
            if (app.online)
                pause(1);
                disp(thingSpeakWrite(app.channelID, 'Fields', [1, 2, 3, 4, 5], 'Values',[app.p1name app.p2name, app.p1score, app.p2score, app.turn], 'WriteKey',app.writeAPI));
                if (app.turn ~= app.player)
                    app.DebugEditField.Value = "Waiting for other player.";
                    app.RollDiceButton.Enable = 'off';
                    app.waitForMessage(5, app.player);

                    app.updateTable(); 

                    app.turn = app.turn*-1;
                    app.RollDiceButton.Enable = 'on';
                    app.DebugEditField.Value = "Your turn.";
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
            
            app.dice = app.dice.unselectAll();
            app.dice = app.dice.updateValues(randi(6, 1, 6));
            
            app.updateDiceImages(app.dice.allValues());
            if (app.turn == 1)
                app.Lamp.Enable = 'on';
                app.Lamp2.Enable = 'off';
            else
                app.Lamp2.Enable = 'on';
                app.Lamp.Enable = 'off';
            end
            
            if (app.turn == 1)
                app.turnNumber = app.turnNumber + 1;
                app.updateTable2();
            end
            
        end
        
        function app = hotDice(app)
            app.Label.Text = "Hot Dice";
            %app.changeTurn();
            app.dice = app.dice.unselectAll();
            app.RollDiceButton.Enable = 'on';
        end

        function rollDice(app, enableBank)
            selected = app.dice.selectedIndices();
      
            for i = 1:7
                for j = 1:6
                    dicenums = app.dice.allValues();
                    if ~ismember(i, selected)
                        dicenums(i) = randi(6);
                    end
                end
                app.updateDiceImages(dicenums);
                pause(0.1)
            end

            for i = 1:6
               if ~ismember(i, selected)
                   app.rolls(i) = randi(6);           
                end
                %pause(0.1);
            end

            app.updateDiceImages(app.rolls);
            
            % Update the DiceArray
            app.dice = app.dice.updateValues(app.rolls);

            % Generate melds.   
            [score, app.triples] = app.dice.generateMelds(app.dice.unselectedValues());
            app.validDice = [app.validDice, app.triples, 1, 5];

            if (enableBank)
                app.BankPointsButton.Enable = 'on';
            else
                app.BankPointsButton.Enable = 'off';
            end

            if score == 0
                app.farkle();
            end

            %app.updateTable();
            app.RollDiceButton.Enable = 'off';
        end
        
        function [] = waitForMessage(app, field, num)
            msgval = 0;
            disp("Waiting for message " + string(num) + "from field " + string(field));
            while msgval ~= num
                msgval = thingSpeakRead(app.channelID, "Fields", field, "ReadKey",app.readAPI);
                pause(5);
            end
        end
        
        function [] = writeNumber(app, field, num)
            pause(1);
            fields = [1,2, 3, 4, 5, 6];
            data = [app.p1name, app.p2name, app.p1score, app.p2score, app.turn, 0];
            data(field) = num;
            disp(data);
            disp("I am writing number" + string(num) + "to field " + string(field));
            disp(thingSpeakWrite(app.channelID, 'Fields', fields, 'Values', data, 'WriteKey',app.writeAPI));
        end

        function num = readNumber(app, field)
            num = thingSpeakRead(app.channelID, "Fields", field, "ReadKey", app.readAPI);
            disp("Read a number " + string(num) + "from field " + field);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.p1name = "p1";
            app.p2name = "p2"; 
            app.p1score = 0;
            app.p2score = 0;
            app.dice = DiceArray(); %
            app.updateTable();
            app.rolls = randi(6, 1, 6);
            app.updateDiceImages(app.rolls);
            app.validDice = [];
            app.numSelected = 0;
            app.turn = 1;
            app.Lamp.Enable = 'on';
            app.Lamp2.Enable = 'off';
            app.BankPointsButton.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            app.SetNameButton.Enable = 'off';
        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            rules
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)

            filepathAudio = fileparts(mfilename('Farkle'));
            [read_audio, speed_audio] = audioread(fullfile(filepathAudio, 'Audio','rolling_dice_audio.mp3'));
            play_Audio = audioplayer(read_audio, speed_audio);
            play(play_Audio);

            % Rolling the unselected dice:
            app.rollDice(true);
            
        end

        % Image clicked function: one
        function oneImageClicked(app, event)
            if (ismember(app.dice.getValue(1), app.validDice) && ~app.dice.isSelected(1))
                app.dice = app.dice.changeSelection(1);
                if (app.dice.isSelected(1)); app.one.BackgroundColor = 'r'; else; app.one.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(1), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(1))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: two
        function twoImageClicked(app, event)
            if (ismember(app.dice.getValue(2), app.validDice) && ~app.dice.isSelected(2))
            app.dice = app.dice.changeSelection(2);
            if (app.dice.isSelected(2)); app.two.BackgroundColor = 'r'; else; app.two.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(2), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(2))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: three
        function threeImageClicked(app, event)
            if (ismember(app.dice.getValue(3), app.validDice) && ~app.dice.isSelected(3))
            app.dice = app.dice.changeSelection(3);
            if (app.dice.isSelected(3)); app.three.BackgroundColor = 'r'; else; app.three.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(3), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(3))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: four
        function fourImageClicked(app, event)
            if (ismember(app.dice.getValue(4), app.validDice) && ~app.dice.isSelected(4))
            app.dice = app.dice.changeSelection(4);
            if (app.dice.isSelected(4)); app.four.BackgroundColor = 'r'; else; app.four.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(4), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(4))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: five
        function fiveImageClicked(app, event)
            if (ismember(app.dice.getValue(5), app.validDice) && ~app.dice.isSelected(5))
            app.dice = app.dice.changeSelection(5);
            if (app.dice.isSelected(5)); app.five.BackgroundColor = 'r'; else; app.five.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(5), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(5))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: six
        function sixImageClicked(app, event)
            if (ismember(app.dice.getValue(6), app.validDice) && ~app.dice.isSelected(6))
            app.dice = app.dice.changeSelection(6);
            if (app.dice.isSelected(6)); app.six.BackgroundColor = 'r'; else; app.six.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(6), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(6))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Button pushed function: BankPointsButton
        function BankPointsButtonPushed(app, event)

            [y, Fs] = audioread('banking_points.mp3');
            sound(y, Fs);

            [score, triples, numFives, numOnes] = app.dice.generateMelds(app.dice.allValues());
           
            if app.turn == 1
                app.p1score = app.p1score + score;
                app.previousRolls(1,:) = app.dice.allValues();
            else
                app.p2score = app.p2score + score;
                app.previousRolls(2, :) = app.dice.allValues();
            end

            if (app.online)
                    if (app.player == 1)
                        app.writeNumber(3, app.p1score);
                    else
                        app.writeNumber(4, app.p2score);
                    end
            end
            app.updateTable();
            allVals = app.dice.allValues();
          
            if (~app.online)
                app.RollDiceButton.Enable = 'on';
            end

            if ((numel(triples)*3 + numFives + numOnes == 6) || all(allVals./allVals(1) == 1))
                app.hotDice();
                return;
            end

            app.changeTurn();
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.game = Game();
            app.dice = DiceArray();
            app.updateTable();
            app.updateTable2();
        end

        % Button pushed function: SetNameButton
        function SetNameButtonPushed(app, event)
       
            if (app.online)
                %thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', app.p2name, 'WriteKey',app.writeAPI);
                if (app.player == 1)
                    app.p1name = string(app.Player1nameEditField.Value);
                    app.writeNumber(1, app.p1name);
                else
                    app.p2name = string(app.Player2nameEditField.Value);
                    app.writeNumber(2, app.p2name);
                end
            else
                app.p1name = string(app.Player1nameEditField.Value);
                app.p2name = string(app.Player2nameEditField.Value);
                
            end
            app.updateTable();
            pause(2);
            %thingSpeakWrite(app.channelID, 'Fields', 3, 'Values', app.p2name, 'WriteKey',app.writeAPI);
        end

        % Button pushed function: OnlineButton
        function OnlineButtonPushed(app, event)
            app.LocalButton.Enable = "off";
            app.OnlineButton.Enable = "off";
            app.online = true;
            app.SetNameButton.Enable = 'on';
            if (thingSpeakRead(app.channelID, "Fields", 6, "ReadKey",app.readAPI) == 1)

                app.player = -1; % Designate self as player 2
                app.DebugEditField.Value = "You are P2";
                app.Player1nameEditField.Enable = 'off';
                app.writeNumber(6, 2);

                app.waitForMessage(5, app.player);
                app.p1score = thingSpeakRead(app.channelID, "Fields", 3, "ReadKey", app.readAPI);
                app.UITable.Data = [app.p1name app.p1score; app.p2name app.p2score];
                app.DebugEditField.Value = "Your turn.";
                app.turn = -1;
                app.RollDiceButton.Enable='on';
            else
                app.writeNumber(6, 1);
                app.player = 1; %Designate self as player 1
                % Display waiting for connection status.
                app.DebugEditField.Value = "You are P1";
                app.Player2nameEditField.Enable = 'off';

                app.waitForMessage(6, 2);
                disp(thingSpeakWrite(app.channelID, 'Fields', [3, 4, 5], 'Values',[app.p1score, app.p2score, app.turn], 'WriteKey',app.writeAPI));
                app.DebugEditField.Value = "P2 has connected.";
                app.turn = 1;
                app.RollDiceButton.Enable ="on";
            end
        end

        % Button pushed function: LocalButton
        function LocalButtonPushed(app, event)
            app.OnlineButton.Enable = "off";
            app.LocalButton.Enable = "off";
            app.RollDiceButton.Enable = "on";
            app.SetNameButton.Enable = "on";
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            message = string(app.EnteramessageforyouropponentEditField.Value)
            pause(2);
            thingSpeakWrite(app.channelID, 'Fields', 7, 'Values',message, 'WriteKey',app.writeAPI);
            % while 
            % while loop - identifier at beginning of message + strip +
            % send to next player
%             '2hi'
%             'hi'
%             'hi'
%             '1hi'
            % show this message to opponent
            disp(msgbox(message))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'prism');
            app.UIFigure.Name = 'MATLAB App';
            app.LocalButton = uibutton(app.UIFigure, 'push');
            app.LocalButton.ButtonPushedFcn = createCallbackFcn(app, @LocalButtonPushed, true);
            app.LocalButton.Text = 'Local';
            app.OnlineButton = uibutton(app.UIFigure, 'push');
            app.OnlineButton.ButtonPushedFcn = createCallbackFcn(app, @OnlineButtonPushed, true);
            app.OnlineButton.Text = 'Online';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player Name'; 'Score'};
            app.UITable.RowName = {};
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.BankPointsButton = uibutton(app.UIFigure, 'push');
            app.BankPointsButton.ButtonPushedFcn = createCallbackFcn(app, @BankPointsButtonPushed, true);
            app.BankPointsButton.Text = 'Bank Points';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Text = 'Roll Dice';
            app.Player1nameEditFieldLabel = uilabel(app.UIFigure);
            app.Player1nameEditFieldLabel.Text = 'Player 1 name:';
            app.Player1nameEditField = uieditfield(app.UIFigure, 'text');
            app.Player2nameEditFieldLabel = uilabel(app.UIFigure);
            app.Player2nameEditFieldLabel.Text = 'Player 2 name:';
            app.Player2nameEditField = uieditfield(app.UIFigure, 'text');
            app.one = uiimage(app.UIFigure);
            app.one.ImageClickedFcn = createCallbackFcn(app, @oneImageClicked, true);
            app.one.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice1.png');
            app.two = uiimage(app.UIFigure);
            app.two.ImageClickedFcn = createCallbackFcn(app, @twoImageClicked, true);
            app.two.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice2.png');
            app.three = uiimage(app.UIFigure);
            app.three.ImageClickedFcn = createCallbackFcn(app, @threeImageClicked, true);
            app.three.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice3.png');
            app.four = uiimage(app.UIFigure);
            app.four.ImageClickedFcn = createCallbackFcn(app, @fourImageClicked, true);
            app.four.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice4.png');
            app.six = uiimage(app.UIFigure);
            app.six.ImageClickedFcn = createCallbackFcn(app, @sixImageClicked, true);
            app.six.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice6.png');
            app.five = uiimage(app.UIFigure);
            app.five.ImageClickedFcn = createCallbackFcn(app, @fiveImageClicked, true);
            app.five.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice5.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'scoring.png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.SetNameButton = uibutton(app.UIFigure, 'push');
            app.SetNameButton.ButtonPushedFcn = createCallbackFcn(app, @SetNameButtonPushed, true);
            app.SetNameButton.Text = 'Set Name';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Turn Number'; 'Player 1'; 'Player 2'};
            app.UITable2.RowName = {};
            app.GameStatusLabel = uilabel(app.UIFigure);
            app.GameStatusLabel.Text = 'GameStatus';
            app.DebugEditFieldLabel = uilabel(app.UIFigure);
            app.DebugEditFieldLabel.Text = 'Debug';
            app.DebugEditField = uieditfield(app.UIFigure, 'text');
            app.FarkleDiceGameLabel = uilabel(app.UIFigure);
            app.FarkleDiceGameLabel.Text = 'Farkle Dice Game';
            app.EnteramessageforyouropponentEditFieldLabel = uilabel(app.UIFigure);
            app.EnteramessageforyouropponentEditFieldLabel.Text = 'Enter a message for your opponent';
            app.EnteramessageforyouropponentEditField = uieditfield(app.UIFigure, 'text');
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Player Name'; 'Message'};
            app.UITable3.RowName = {};
            app.ChatBoxLabel = uilabel(app.UIFigure);
            app.ChatBoxLabel.Text = 'Chat Box';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end